classdef main_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        EmotionRecognitionByUsingFacialExpressionLabel  matlab.ui.control.Label
        Panel                    matlab.ui.container.Panel
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes_4                 matlab.ui.control.UIAxes
        UIAxes_5                 matlab.ui.control.UIAxes
        UIAxes_6                 matlab.ui.control.UIAxes
        UIAxes_7                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
        initializeProjectButton  matlab.ui.control.Button
        Panel_2                  matlab.ui.container.Panel
        pickimgButton            matlab.ui.control.Button
        UIAxes2                  matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Button pushed function: initializeProjectButton
        function initializeProjectButtonPushed(app, event)
            % coding starts from here.
            % [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            % filename=strcat(pathname,filename);
            % a=imread(filename);
            a = "I:\Collage project\matlab app designer learning\learning app\Images\img1.jpg"
            imshow(a,'Parent',app.UIAxes);
            a = "I:\Collage project\matlab app designer learning\learning app\Images\img2.jpg"
            imshow(a,'Parent',app.UIAxes_2);
            a = "I:\Collage project\matlab app designer learning\learning app\Images\img3.jpg"
            imshow(a,'Parent',app.UIAxes_3);
            a = "I:\Collage project\matlab app designer learning\learning app\Images\img4.jpg"
            imshow(a,'Parent',app.UIAxes_4);
            a = "I:\Collage project\matlab app designer learning\learning app\Images\img5.jpg"
            imshow(a,'Parent',app.UIAxes_5);
            a = "I:\Collage project\matlab app designer learning\learning app\Images\img6.jpg"
            imshow(a,'Parent',app.UIAxes_6);
            a = "I:\Collage project\matlab app designer learning\learning app\Images\img7.jpg"
            imshow(a,'Parent',app.UIAxes_7);
        end

        % Button pushed function: pickimgButton
        function pickimgButtonPushed(app, event)
            a = "I:\Collage project\matlab app designer learning\learning app\Images\img7.jpg"
            imshow(a,'Parent',app.Panel_2);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.EmotionRecognitionByUsingFacialExpressionLabel = uilabel(app.UIFigure);
            app.EmotionRecognitionByUsingFacialExpressionLabel.Text = 'Emotion Recognition By Using Facial Expression';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.Panel);
            title(app.UIAxes_2, 'Sad')
            app.UIAxes_3 = uiaxes(app.Panel);
            title(app.UIAxes_3, 'Sad')
            app.UIAxes_4 = uiaxes(app.Panel);
            title(app.UIAxes_4, 'Sad')
            app.UIAxes_5 = uiaxes(app.Panel);
            title(app.UIAxes_5, 'Sad')
            app.UIAxes_6 = uiaxes(app.Panel);
            title(app.UIAxes_6, 'Sad')
            app.UIAxes_7 = uiaxes(app.Panel);
            title(app.UIAxes_7, 'Sad')
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Sad')
            app.initializeProjectButton = uibutton(app.UIFigure, 'push');
            app.initializeProjectButton.ButtonPushedFcn = createCallbackFcn(app, @initializeProjectButtonPushed, true);
            app.initializeProjectButton.Text = 'initialize Project';
            app.Panel_2 = uipanel(app.UIFigure);
            app.pickimgButton = uibutton(app.UIFigure, 'push');
            app.pickimgButton.ButtonPushedFcn = createCallbackFcn(app, @pickimgButtonPushed, true);
            app.pickimgButton.Text = 'pick img';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = main_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end